classdef ControlStudy_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ModelLabel                 matlab.ui.control.Label
        HTML                       matlab.ui.control.HTML
        SystemnoiseLabel           matlab.ui.control.Label
        SysN_EF                    matlab.ui.control.NumericEditField
        SensornoiseLabel           matlab.ui.control.Label
        SenN_EF                    matlab.ui.control.NumericEditField
        LimitsofcommandLabel       matlab.ui.control.Label
        LoC_EF                     matlab.ui.control.NumericEditField
        OffsetDisturbanceLabel     matlab.ui.control.Label
        OD_EF                      matlab.ui.control.NumericEditField
        SensorOffsetLabel          matlab.ui.control.Label
        SO_EF                      matlab.ui.control.NumericEditField
        SensorDelayLabel           matlab.ui.control.Label
        SD_EF                      matlab.ui.control.NumericEditField
        RightPanel                 matlab.ui.container.Panel
        Resp_Fig                   matlab.ui.control.UIAxes
        Input_Fig                  matlab.ui.control.UIAxes
        ModeltypeDropDownLabel     matlab.ui.control.Label
        ModeltypeDropDown          matlab.ui.control.DropDown
        SamplingTimesLabel         matlab.ui.control.Label
        TS_EF                      matlab.ui.control.NumericEditField
        PgainLabel                 matlab.ui.control.Label
        Pgain_EF                   matlab.ui.control.NumericEditField
        IgainLabel                 matlab.ui.control.Label
        Igain_EF                   matlab.ui.control.NumericEditField
        DgainLabel                 matlab.ui.control.Label
        Dgain_EF                   matlab.ui.control.NumericEditField
        OffNoFeedbackwhenOffLabel  matlab.ui.control.Label
        FB_SW                      matlab.ui.control.Switch
        ResponseLabel              matlab.ui.control.Label
        SimulationTimesLabel       matlab.ui.control.Label
        SimTime_EF                 matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Copyright 2020 The MathWorks, Inc.
    
    methods (Access = private)
        
        function calc_simulation(app)
            % 初期化 / initialize
            model_name = 'ControlStudy_model';
            param;
            mode = check_mode(app.ModeltypeDropDown.Value);
            sw_flag = check_FB_sw(app.FB_SW.Value);
            
            % パラメータをセット / set parameters
            simin_data = Simulink.SimulationInput(model_name);
            simin_data = simin_data.setVariable('TimeStep', app.TS_EF.Value);
            simin_data = simin_data.setVariable('SimEndTime', app.SimTime_EF.Value);
            simin_data = simin_data.setVariable('P_gain_model', app.Pgain_EF.Value);
            simin_data = simin_data.setVariable('I_gain_model', app.Igain_EF.Value);
            simin_data = simin_data.setVariable('D_gain_model', app.Dgain_EF.Value);
            simin_data = simin_data.setVariable('Limits', app.LoC_EF.Value);
            simin_data = simin_data.setVariable('Off_dist', app.OD_EF.Value);
            simin_data = simin_data.setVariable('Sens_off', app.SO_EF.Value);
            simin_data = simin_data.setVariable('Sens_delay', app.SD_EF.Value);
            simin_data = simin_data.setVariable('System_noise', app.SysN_EF.Value);
            simin_data = simin_data.setVariable('Sensor_noise', app.SenN_EF.Value);
            simin_data = simin_data.setVariable('FB_off', ~sw_flag);
            
            simin_data = simin_data.setModelParameter('SimulationMode', 'Rapid');
            simin_data = simin_data.setModelParameter('RapidAcceleratorUpToDateCheck', 'off');
            
            % 実行 / run
            simout_data = sim(simin_data);
            
            switch mode
                case int32(1)
                    time_ref_sig_sim = simout_data.yout{1}.Values.Time;
                    val_ref_sig_sim = simout_data.yout{1}.Values.Data;
                    time_target_sig_sim = simout_data.yout{2}.Values.Time;
                    val_target_sig_sim = simout_data.yout{2}.Values.Data;
                    time_comm_sig_sim = simout_data.yout{3}.Values.Time;
                    val_comm_sig_sim = simout_data.yout{3}.Values.Data;
                case int32(2)
                    time_ref_sig_sim = simout_data.yout{4}.Values.Time;
                    val_ref_sig_sim = simout_data.yout{4}.Values.Data;
                    time_target_sig_sim = simout_data.yout{5}.Values.Time;
                    val_target_sig_sim = simout_data.yout{5}.Values.Data;
                    time_comm_sig_sim = simout_data.yout{6}.Values.Time;
                    val_comm_sig_sim = simout_data.yout{6}.Values.Data;
                case int32(3)
                    time_ref_sig_sim = simout_data.yout{7}.Values.Time;
                    val_ref_sig_sim = simout_data.yout{7}.Values.Data;
                    time_target_sig_sim = simout_data.yout{8}.Values.Time;
                    val_target_sig_sim = simout_data.yout{8}.Values.Data;
                    time_comm_sig_sim = simout_data.yout{9}.Values.Time;
                    val_comm_sig_sim = simout_data.yout{9}.Values.Data;
                case int32(4)
                    time_ref_sig_sim = simout_data.yout{10}.Values.Time;
                    val_ref_sig_sim = simout_data.yout{10}.Values.Data;
                    time_target_sig_sim = simout_data.yout{11}.Values.Time;
                    val_target_sig_sim = simout_data.yout{11}.Values.Data;
                    time_comm_sig_sim = simout_data.yout{12}.Values.Time;
                    val_comm_sig_sim = simout_data.yout{12}.Values.Data;
                otherwise
                    time_ref_sig_sim = simout_data.yout{1}.Values.Time;
                    val_ref_sig_sim = simout_data.yout{1}.Values.Data;
                    time_target_sig_sim = simout_data.yout{2}.Values.Time;
                    val_target_sig_sim = simout_data.yout{2}.Values.Data;
                    time_comm_sig_sim = simout_data.yout{3}.Values.Time;
                    val_comm_sig_sim = simout_data.yout{3}.Values.Data;
            end
            
            % 表示 / show the results
            hold(app.Resp_Fig, 'off');
            plot(app.Resp_Fig, time_ref_sig_sim, val_ref_sig_sim);
            hold(app.Resp_Fig, 'on');
            plot(app.Resp_Fig, time_target_sig_sim, val_target_sig_sim);
            legend(app.Resp_Fig, '指令値 / Command', '制御量 / Measured Outputs');
            hold(app.Resp_Fig, 'off');
            plot(app.Input_Fig, time_comm_sig_sim, val_comm_sig_sim);
            legend(app.Input_Fig, '操作量 / Manipulated Variables');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 外観の設定 / set appearance
            app.UIFigure.Name = 'PID制御のチューニング練習 / PID Tuning Boot Camp';
            % 最初の応答を計算 / first run
            calc_simulation(app);
        end

        % Value changed function: Pgain_EF
        function Pgain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: FB_SW
        function FB_SWValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: Igain_EF
        function Igain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: Dgain_EF
        function Dgain_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: TS_EF
        function TS_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: SimTime_EF
        function SimTime_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: LoC_EF
        function LoC_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: OD_EF
        function OD_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: SO_EF
        function SO_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: SD_EF
        function SD_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: SysN_EF
        function SysN_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Value changed function: SenN_EF
        function SenN_EFValueChanged(app, event)
            % シミュレーションを再実行 / Rerun model
            calc_simulation(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {622, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelLabel = uilabel(app.LeftPanel);
            app.ModelLabel.Text = 'モデル / Model';
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '.\ControlStudy_model\webview.html';
            app.SystemnoiseLabel = uilabel(app.LeftPanel);
            app.SystemnoiseLabel.Text = 'System noise';
            app.SysN_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SysN_EF.Limits = [1e-06 Inf];
            app.SysN_EF.ValueChangedFcn = createCallbackFcn(app, @SysN_EFValueChanged, true);
            app.SysN_EF.Value = 0.2;
            app.SensornoiseLabel = uilabel(app.LeftPanel);
            app.SensornoiseLabel.Text = 'Sensor noise';
            app.SenN_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SenN_EF.Limits = [1e-06 Inf];
            app.SenN_EF.ValueChangedFcn = createCallbackFcn(app, @SenN_EFValueChanged, true);
            app.SenN_EF.Value = 0.1;
            app.LimitsofcommandLabel = uilabel(app.LeftPanel);
            app.LimitsofcommandLabel.Text = 'Limits of command';
            app.LoC_EF = uieditfield(app.LeftPanel, 'numeric');
            app.LoC_EF.Limits = [0 Inf];
            app.LoC_EF.ValueChangedFcn = createCallbackFcn(app, @LoC_EFValueChanged, true);
            app.LoC_EF.Value = 2;
            app.OffsetDisturbanceLabel = uilabel(app.LeftPanel);
            app.OffsetDisturbanceLabel.Text = 'Offset Disturbance';
            app.OD_EF = uieditfield(app.LeftPanel, 'numeric');
            app.OD_EF.ValueChangedFcn = createCallbackFcn(app, @OD_EFValueChanged, true);
            app.OD_EF.Value = 1;
            app.SensorOffsetLabel = uilabel(app.LeftPanel);
            app.SensorOffsetLabel.Text = 'Sensor Offset';
            app.SO_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SO_EF.ValueChangedFcn = createCallbackFcn(app, @SO_EFValueChanged, true);
            app.SensorDelayLabel = uilabel(app.LeftPanel);
            app.SensorDelayLabel.Text = 'Sensor Delay';
            app.SD_EF = uieditfield(app.LeftPanel, 'numeric');
            app.SD_EF.Limits = [0 100];
            app.SD_EF.ValueChangedFcn = createCallbackFcn(app, @SD_EFValueChanged, true);
            app.SD_EF.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.Resp_Fig = uiaxes(app.RightPanel);
            title(app.Resp_Fig, 'システムの応答 / Response of system')
            xlabel(app.Resp_Fig, 'time[s]')
            ylabel(app.Resp_Fig, '-')
            app.Input_Fig = uiaxes(app.RightPanel);
            title(app.Input_Fig, 'PID制御器の出力 / Output of PID Controller')
            xlabel(app.Input_Fig, 'time[s]')
            ylabel(app.Input_Fig, '-')
            app.ModeltypeDropDownLabel = uilabel(app.RightPanel);
            app.ModeltypeDropDownLabel.Text = 'Model type:';
            app.ModeltypeDropDown = uidropdown(app.RightPanel);
            app.ModeltypeDropDown.Items = {'モデル0理想', 'モデル0現実', 'モデル1理想', 'モデル1現実'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Value = 'モデル0理想';
            app.SamplingTimesLabel = uilabel(app.RightPanel);
            app.SamplingTimesLabel.Text = 'Sampling Time[s]';
            app.TS_EF = uieditfield(app.RightPanel, 'numeric');
            app.TS_EF.Limits = [0.001 0.1];
            app.TS_EF.ValueChangedFcn = createCallbackFcn(app, @TS_EFValueChanged, true);
            app.TS_EF.Value = 0.01;
            app.PgainLabel = uilabel(app.RightPanel);
            app.PgainLabel.Text = 'P gain';
            app.Pgain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Pgain_EF.ValueChangedFcn = createCallbackFcn(app, @Pgain_EFValueChanged, true);
            app.IgainLabel = uilabel(app.RightPanel);
            app.IgainLabel.Text = 'I gain';
            app.Igain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Igain_EF.ValueChangedFcn = createCallbackFcn(app, @Igain_EFValueChanged, true);
            app.DgainLabel = uilabel(app.RightPanel);
            app.DgainLabel.Text = 'D gain';
            app.Dgain_EF = uieditfield(app.RightPanel, 'numeric');
            app.Dgain_EF.ValueChangedFcn = createCallbackFcn(app, @Dgain_EFValueChanged, true);
            app.OffNoFeedbackwhenOffLabel = uilabel(app.RightPanel);
            app.OffNoFeedbackwhenOffLabel.Text = {'Offにするとフィードバックが'; '切断されます / No Feedback when Off'};
            app.FB_SW = uiswitch(app.RightPanel, 'slider');
            app.FB_SW.ValueChangedFcn = createCallbackFcn(app, @FB_SWValueChanged, true);
            app.FB_SW.Value = 'On';
            app.ResponseLabel = uilabel(app.RightPanel);
            app.ResponseLabel.Text = '応答 / Response';
            app.SimulationTimesLabel = uilabel(app.RightPanel);
            app.SimulationTimesLabel.Text = 'Simulation Time[s]';
            app.SimTime_EF = uieditfield(app.RightPanel, 'numeric');
            app.SimTime_EF.Limits = [5 100];
            app.SimTime_EF.ValueChangedFcn = createCallbackFcn(app, @SimTime_EFValueChanged, true);
            app.SimTime_EF.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlStudy_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end